classdef Eqfinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        GoButton                       matlab.ui.control.Button
        EventdetectionandlocalisationLabel  matlab.ui.control.Label
        ProgressGaugeLabel             matlab.ui.control.Label
        ProgressGauge                  matlab.ui.control.LinearGauge
        UITable2                       matlab.ui.control.Table
        Lamp                           matlab.ui.control.Lamp
        TabGroup                       matlab.ui.container.TabGroup
        DataTab                        matlab.ui.container.Tab
        StartDateDropDownLabel         matlab.ui.control.Label
        StartDateDropDown              matlab.ui.control.DropDown
        EndDateDropDownLabel           matlab.ui.control.Label
        EndDateDropDown                matlab.ui.control.DropDown
        CategoryEditFieldLabel         matlab.ui.control.Label
        CategoryEditField              matlab.ui.control.EditField
        InputnameEditFieldLabel        matlab.ui.control.Label
        InputnameEditField             matlab.ui.control.EditField
        OutputnameEditFieldLabel       matlab.ui.control.Label
        OutputnameEditField            matlab.ui.control.EditField
        UITable                        matlab.ui.control.Table
        ChooseCoordinateFileButton     matlab.ui.control.Button
        STALTATab                      matlab.ui.container.Tab
        TriggerEditFieldLabel          matlab.ui.control.Label
        TriggerEditField               matlab.ui.control.NumericEditField
        STAWindowEditFieldLabel        matlab.ui.control.Label
        STAWindowEditField             matlab.ui.control.NumericEditField
        LTAWindowEditFieldLabel        matlab.ui.control.Label
        LTAWindowEditField             matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel        matlab.ui.control.Label
        ToleranceEditField             matlab.ui.control.NumericEditField
        LocalizationTab                matlab.ui.container.Tab
        startzEditFieldLabel           matlab.ui.control.Label
        startzEditField                matlab.ui.control.NumericEditField
        stepzEditFieldLabel            matlab.ui.control.Label
        stepzEditField                 matlab.ui.control.NumericEditField
        endzEditFieldLabel             matlab.ui.control.Label
        endzEditField                  matlab.ui.control.NumericEditField
        startvEditFieldLabel           matlab.ui.control.Label
        startvEditField                matlab.ui.control.NumericEditField
        stepvEditFieldLabel            matlab.ui.control.Label
        stepvEditField                 matlab.ui.control.NumericEditField
        endvEditFieldLabel             matlab.ui.control.Label
        endvEditField                  matlab.ui.control.NumericEditField
        ThicknesCrustEditFieldLabel    matlab.ui.control.Label
        ThicknesCrustEditField         matlab.ui.control.NumericEditField
        vpvsEditFieldLabel             matlab.ui.control.Label
        vpvsEditField                  matlab.ui.control.NumericEditField
        NormDropDownLabel              matlab.ui.control.Label
        NormDropDown                   matlab.ui.control.DropDown
        InversionMethodeDropDownLabel  matlab.ui.control.Label
        InversionMethodeDropDown       matlab.ui.control.DropDown
        OptionsTab                     matlab.ui.container.Tab
        PlotSTALTACheckBox             matlab.ui.control.CheckBox
        PlotEventsCheckBox             matlab.ui.control.CheckBox
        InversionCheckBox              matlab.ui.control.CheckBox
        ExcludeoldbadeventsCheckBox    matlab.ui.control.CheckBox
        ShowalsoknowneventsCheckBox    matlab.ui.control.CheckBox
        ShowjustknowneventsCheckBox    matlab.ui.control.CheckBox
        RelocateknowneventsCheckBox    matlab.ui.control.CheckBox
        withpickingCheckBox            matlab.ui.control.CheckBox
        plotdensityofEventsCheckBox    matlab.ui.control.CheckBox
        ResultsLabel                   matlab.ui.control.Label
        InputLabel                     matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = main(app,Trigger,shortWindowSec,longWindowSec,...
                tolSec,plotStaLta_Flag,plot_Flag,inversion,methode,coord,start,ende)
            addpath(genpath(join([pwd,'\bin'])));
            statnum = length(coord);
            zrange = [app.startzEditField.Value,app.stepzEditField.Value, app.endzEditField.Value];
            vprange = [app.startvEditField.Value,app.stepvEditField.Value, app.endvEditField.Value];
            vsrange = [vprange(1)/app.vpvsEditField.Value,vprange(2)/app.vpvsEditField.Value,vprange(3)/app.vpvsEditField.Value];
            oldnameread = app.InputnameEditField.Value;
            oldnamewrite = app.OutputnameEditField.Value;
            oldnamereadfile = join([oldnameread,'.mat']);
            oldnamewritefile = join([oldnamewrite,'.mat']);
            %% Looking for old calculations 
            if isfile(oldnamereadfile)
                load(oldnamereadfile);
                Olddates = Data.Olddates;
                try
                    Baddates = Data.Baddates;
                catch
                    Baddates = [];
                end
                Oldexisted = true;
                if app.RelocateknowneventsCheckBox.Value
                    Time = datetime("now");
                    Arrivaltimes_1 = NaT;
                    Arrivaltimes_2 = NaT;
                    Arrivaltimes_3 = NaT;
                    Arrivaltimes_4 = NaT;
                    Arrivaltimes_5 = NaT;
                    Arrivaltimes_6 = NaT;
                    Arrivaltimes_7 = NaT;
                    Arrivaltimes_8 = NaT;
                    Arrivaltimes_9 = NaT;
                    Arrivaltimes_10 = NaT;
                    lat_hypo = 0;
                    lon_hypo = 0;
                    alt_hypo = 0;
                    v = 0;
                    v2 = 0;
                    EvType = NaN;
                    signal.tn = [] ;
                    signal.dn = [] ;
                    stations.coordp = [];
                    stations.coords = [];
                    Residuum = NaN;
                    Olddates2 = table(Time,Arrivaltimes_1,Arrivaltimes_2,...
                        Arrivaltimes_3,Arrivaltimes_4,Arrivaltimes_5,...
                        Arrivaltimes_6,Arrivaltimes_7,Arrivaltimes_8,...
                        Arrivaltimes_9,Arrivaltimes_10,...
                        lat_hypo,lon_hypo,alt_hypo,v,v2,EvType,signal,stations,Residuum);
                    Baddates2 = table(Time,EvType);
                end
            else
                if app.ShowjustknowneventsCheckBox.Value
                    return
                end
                Time = datetime("now");
                Arrivaltimes_1 = NaT;
                Arrivaltimes_2 = NaT;
                Arrivaltimes_3 = NaT;
                Arrivaltimes_4 = NaT;
                Arrivaltimes_5 = NaT;
                Arrivaltimes_6 = NaT;
                Arrivaltimes_7 = NaT;
                Arrivaltimes_8 = NaT;
                Arrivaltimes_9 = NaT;
                Arrivaltimes_10 = NaT;
                lat_hypo = 0;
                lon_hypo = 0;
                alt_hypo = 0;
                v = 0;
                EvType = NaN;
                signal.tn = [] ;
                signal.dn = [] ;
                stations.coordp = [];
                stations.coords = [];
                Residuum = NaN;
                
                Olddates = table(Time,Arrivaltimes_1,Arrivaltimes_2,...
                    Arrivaltimes_3,Arrivaltimes_4,Arrivaltimes_5,...
                    Arrivaltimes_6,Arrivaltimes_7,Arrivaltimes_8,...
                    Arrivaltimes_9,Arrivaltimes_10,...
                    lat_hypo,lon_hypo,alt_hypo,v,EvType,signal,stations,Residuum);
                Baddates = table(Time,EvType);
                Oldexisted = false;
            end
            counter = 5;
            %% Read Bad events
            if ~app.ShowjustknowneventsCheckBox.Value && ~app.RelocateknowneventsCheckBox.Value                
                avstat = 'a2u' ;
                %%
                %Input Folder:
                files = dir('data-in');
                ges = [];
                erg = [];
                folders = {files.name};
                allstat = length(folders)-2;
                oldnum = 0;
                for i = 1:allstat
                    direct = join([pwd,'\data-in\', folders{i+2}]);
                    names = dir(direct);
                    if i > 1 && numel(names) ~= oldnum
                        error('There is not the same amount of data in all folders')
                    end
                    Names.(folders{i+2}) = {names.name};
                    oldnum = numel(names);
                end
                %% List of dates
                finaldates =[];
                for i = 1:allstat
                    direct = join([pwd,'\data-in\', folders{i+2}]);
                    names = dir(direct);
                    strdates = string({names.name});
                    strdates = strdates(3:end);
                    for j = 1:length(strdates)
                        singledate = char(strdates(j));
                        singledate = singledate(6:11);
                        year = strcat('20',singledate(1:2));
                        month = singledate(3:4);
                        day = singledate(5:6);
                        singledate = string(strcat(day,'.',month,'.',year));
                        strdates(j) = singledate;
                    end
                    finaldates = cat(2,finaldates,strdates);
                    oldnum = numel(names);
                end
                posstart = find(finaldates == start);
                posend = find(finaldates == ende);
                posstart = posstart(1);
                posend = posend(1);
                z = 1;
                for i = 2+posstart:posend+2
                    %% Show Progress
                    app.ProgressGauge.Value = 100/(posend-posstart+1)*(i-posstart-2);
                    pause(0.1)
                    
                    ges = [];
                    backup = [];
                    %% Read Seismograms
                    for j = 1:allstat
                        direct = strjoin([pwd,'\data-in\', folders{j+2},'\',Names.(folders{j+2})(i)],'')
                        try
                            X = rdmseed(direct);
                        catch
                            continue
                        end
                        %% Find Events with STA/LTA
                        D.(folders{j+2}).file = extractAfter(direct,83);
                        D.(folders{j+2}).t = cat(1,X.t);
                        D.(folders{j+2}).d = cat(1,X.d);
                        D.(folders{j+2}).d = D.(folders{j+2}).d-mean(D.(folders{j+2}).d);
                        shortWindow = round(shortWindowSec*1008/10)-1;
                        longWindow = round(longWindowSec*1008/10)-1;
                        D.(folders{j+2}).sta = movmean(abs(D.(folders{j+2}).d),[0,shortWindow]);
                        D.(folders{j+2}).lta = movmean(abs(D.(folders{j+2}).d),[0,longWindow]);
                        D.(folders{j+2}).ind = D.(folders{j+2}).sta./D.(folders{j+2}).lta;
                        p=D.(folders{j+2}).ind(D.(folders{j+2}).ind>Trigger);
                        pt = D.(folders{j+2}).t(D.(folders{j+2}).ind>Trigger);
                        tolerance = tolSec*1.6667*10e-04/10;
                        tol = tolerance/max(abs(pt));
                        if isempty(tol)
                            u = [];
                        else
                            u = uniquetol(pt,tol);
                        end
                
                        station = {X.StationIdentifierCode};
                        name = join([station{1},num2str(i-2)]);
                        ges = cat(1,ges,u);
                        backup = cat(1,backup,j*ones(length(u),1));
                    end
                    
                    try
                        tolerance = tolSec*1.6667*10e-04/140
                        max(abs(ges))
                        tol = tolerance/max(abs(ges));
                        [ges,I] = sort(ges);
                        backup = backup(I);
                        [c,ia] = uniquetol(ges,tol);
                        d = diff(ia);
                        d = cat(1,d,length(ges)-ia(end)+1);
%                         edates = c(d==statnum);
                        edates = c(d>2);
                        erg = cat(1,erg,datetime(edates,'ConvertFrom','datenum'));
                    catch
                        edates = [];
                    end
                        %% Inversion
                        if app.InversionCheckBox.Value
                            for k= 1:length(edates)
                                pos = find(c == edates(k));
                                pstart(z,:) = datetime(ges(ia(pos):ia(pos)+allstat-1),'ConvertFrom','datenum');
                                startnumber(z,:) = backup(ia(pos):(ia(pos)+allstat-1));
                                pinput = pstart(z,:);
                                pinput = pinput(startnumber(z,:));
                                if app.ExcludeoldbadeventsCheckBox.Value
                                    badFlag = ~ismember(string(pinput(1)),string(Baddates.Time));
                                else
                                    badFlag = true;
                                end
                                if ~app.ShowalsoknowneventsCheckBox.Value
                                    oldFlag = ~ismember(string(pinput(1)),string(Olddates.Time));
                                else
                                    oldFlag = true;
                                end
                                if oldFlag && badFlag
                                    if plot_Flag
                                        [tp,button,EvType,signal] = plotSnippet5(app,D,datetime(edates(k),'ConvertFrom','datenum'),pinput);
                                    end
                                    EvType = string(EvType);
                                    tp = datetime(tp,'ConvertFrom','datenum')';
                                    if max(button) == 1
                                        tpo = tp
                                        tpp = tp(1:2:length(tp)-1)
                                        tps = tp(2:2:length(tp))
                                        buttonp = button(1:2:length(tp)-1)
                                        buttons = button(2:2:length(tp))
                                        tpp = tpp(logical(buttonp))
                                        tps = tps(logical(buttons))
                                        sumbutton = buttonp +buttons
                                        coordinp = coord(logical(buttonp),:);
                                        coordins = coord(logical(buttons),:);
                                        if strcmp(methode,'grid')
                                            tic
                                            [lon(z),lat(z),h(z),vpges(z),vsges(z),res(z)] = invtest(app,coordinp,coordins,tpp,tps,vprange,vsrange,counter,zrange);
                                            toc
                                        end
                                        if strcmp(methode,'linear')
                                            vpges(z) = 6000;
                                            vsges(z) = 4000;
                                            [lon(z),lat(z),h(z),t(z),error(z)] = invloc(app,coordinp,coordins,tpp,tps,6000,4000,zrange);
                                        end
                                        if strcmp(methode,'genetic')
                                            tic
                                            [lon(z),lat(z),h(z),t(z),vges(z),error(z)] = evoloc3app(app,coordinp,coordins,tpp,tps,zrange,vrange);
                                            toc
                                        end
                                        lat = lat(lat~=0);
                                        lon = lon(lon~=0);
                                        h = h(lat~=0);
                                        res = res(lat~=0);
                                        app.UITable2.Data = [lat',lon',h'];
                                        pause(0.1)
                                        Time = pinput(1);
                                        button(button>1)=1;
                                        tpo(~logical(button)) = NaT;
                                        tpo
                                        Arrivaltimes_1 = tpo(1);
                                        Arrivaltimes_2 = tpo(2);
                                        try
                                            Arrivaltimes_3 = tpo(3);
                                        catch
                                            Arrivaltimes_3 = NaT;
                                        end
                                        try
                                            Arrivaltimes_4 = tpo(4);
                                        catch
                                            Arrivaltimes_4 = NaT;
                                        end
                                        try
                                            Arrivaltimes_5 = tpo(5);
                                        catch
                                            Arrivaltimes_5 = NaT;
                                        end
                                        try
                                            Arrivaltimes_6 = tpo(6);
                                        catch
                                            Arrivaltimes_6 = NaT;
                                        end
                                        try
                                            Arrivaltimes_7 = tpo(7);
                                        catch
                                            Arrivaltimes_7 = NaT;
                                        end
                                        try
                                            Arrivaltimes_8 = tpo(8);
                                        catch
                                            Arrivaltimes_8 = NaT;
                                        end
                                        try
                                            Arrivaltimes_9 = tpo(9);
                                        catch
                                            Arrivaltimes_9 = NaT;
                                        end
                                        try
                                            Arrivaltimes_10 = tpo(10);
                                        catch
                                            Arrivaltimes_10 = NaT;
                                        end
                                        lat_hypo =lat(z);
                                        lon_hypo =lon(z);
                                        alt_hypo =h(z);
                                        Residuum = res(z);
                                        v = vpges(z);
                                        stations.coordp = coordinp;
                                        stations.coords = coordins;
                                        
                                        if ~ismember(string(pinput(1)),string(Olddates.Time))
                                            entrie = table(Time,Arrivaltimes_1,Arrivaltimes_2,...
                                                Arrivaltimes_3,Arrivaltimes_4,Arrivaltimes_5,...
                                                Arrivaltimes_6,Arrivaltimes_7,Arrivaltimes_8,...
                                                Arrivaltimes_9,Arrivaltimes_10,...
                                                lat_hypo,lon_hypo,alt_hypo,v,EvType,signal,stations,Residuum)
                                            Olddates = [Olddates;entrie];
                                            
%                                             if Oldexisted
%                                                delete('Olddates.csv')
%                                             end
%                                             writetable(Olddates,'Olddates.csv');
                                            Data.Olddates = Olddates;
                                            if Oldexisted
                                                delete(oldnamewritefile)
                                            end
                                            save(oldnamewritefile,'Data');
                                        end
                                        counter = counter+1;
                                        z = z+1;
                                    elseif ~ismember(string(pinput(1)),string(Baddates.Time))
                                        Time = pinput(1);
                                        Baddates = [Baddates;table(Time,EvType)];
%                                         if Badexisted
%                                            delete('Baddates.csv')
%                                         end
%                                         writetable(Baddates,'Baddates.csv');
                                        Data.Baddates = Baddates;
                                        if Oldexisted
                                            delete(oldnamewritefile)
                                        end
                                        save(oldnamewritefile,'Data');
                                    end             
                                    
                                elseif ismember(string(pinput(1)),string(Olddates.Time))
                                    [boolold,posold] = ismember(string(pinput(1)),string(Olddates.Time));
                                    lat(z) = Olddates.lat_hypo(posold);
                                    lon(z) = Olddates.lon_hypo(posold);
                                    h(z) = Olddates.alt_hypo(posold);
                                    vges(z) = Olddates.v(posold);
                                    app.UITable2.Data = [lat',lon',h'];
                                    pause(0.1)
                                    z = z+1;
                                end
                                
                            end
                        end
                        
                        %% Plotting
                        if ~inversion & plot_Flag
                            for k= 1:length(edates)
                                pos = find(c == edates(k));
                                pstart(z,:) = datetime(ges(ia(pos):ia(pos)+allstat-1),'ConvertFrom','datenum');
                                startnumber(z,:) = backup(ia(pos):(ia(pos)+allstat-1));
                                pinput = pstart(z,:);
                                pinput = pinput(startnumber(z,:));
                                counter = counter+1;
                                plotSnippet3(D,datetime(edates(k),'ConvertFrom','datenum'),pinput);
                                z = z+1;
                            end
                        end
                        if plotStaLta_Flag
                            plotStaLta2(D,datetime(c(d==statnum),'ConvertFrom','datenum'),avstat);
                        end                 
                end
            elseif ~app.RelocateknowneventsCheckBox.Value
                
                lat = Olddates.lat_hypo(2:end)';
                lon = Olddates.lon_hypo(2:end)';
                h = Olddates.alt_hypo(2:end)';
                v = Olddates.v(2:end);
                a1 = Olddates.Arrivaltimes_1(2:end);
                a2 = Olddates.Arrivaltimes_2(2:end);
                a3 = Olddates.Arrivaltimes_3(2:end);
                a4 = Olddates.Arrivaltimes_4(2:end);
                a5 = Olddates.Arrivaltimes_5(2:end);
                a6 = Olddates.Arrivaltimes_6(2:end);
                a7 = Olddates.Arrivaltimes_7(2:end);
                a8 = Olddates.Arrivaltimes_8(2:end);
                a9 = Olddates.Arrivaltimes_9(2:end);
                a10 = Olddates.Arrivaltimes_10(2:end);
                avec = [a1,a2,a3,a4,a5,a6,a7,a8,a9,a10];
                gesa = zeros(1,length(a1));
                for acount = 1:length(a1)
                    for acount2 = 1:10
                        if isnat(avec(acount,acount2))
                            gesa(acount) = gesa(acount);
                        else
                            gesa(acount) = gesa(acount)+1;
                        end
                    end
                end
                res = Olddates.Residuum(2:end);
                od = Olddates.EvType(2:end);
                resmax = str2num(string(inputdlg('Enter the maximum residue to show:')));
                amin = str2num(string(inputdlg('Minimum number of arrivaltimes:')));
                lat =lat(res<resmax & gesa'>= amin);
                lon =lon(res<resmax & gesa'>= amin);
                h =h(res<resmax & gesa'>= amin);
                v =v(res<resmax & gesa'>= amin);
                od =od(res<resmax & gesa'>= amin);
                
                if ~strcmp(app.CategoryEditField.Value,'all') 
                    lat = lat(strcmp(od,app.CategoryEditField.Value));
                    lon = lon(strcmp(od,app.CategoryEditField.Value));
                    h = h(strcmp(od,app.CategoryEditField.Value));
                    v = v(strcmp(od,app.CategoryEditField.Value));
                end
                
            end
            z = 1;
            if app.RelocateknowneventsCheckBox.Value && ~app.withpickingCheckBox.Value
                
                for w = 2:length(Olddates.Time)
                      %% Show Progress
                      app.ProgressGauge.Value = 100/length(Olddates.Time)*w;
                      pause(0.1)
                      if strcmp(Olddates.EvType(w),app.CategoryEditField.Value)
                        
                        tpo = [Olddates.Arrivaltimes_1(w),Olddates.Arrivaltimes_2(w),...
                            Olddates.Arrivaltimes_3(w),Olddates.Arrivaltimes_4(w),...
                            Olddates.Arrivaltimes_5(w),Olddates.Arrivaltimes_6(w),...
                            Olddates.Arrivaltimes_7(w),Olddates.Arrivaltimes_8(w),...
                            Olddates.Arrivaltimes_9(w),Olddates.Arrivaltimes_10(w)]
                        tp = tpo;
                        tpp = tp(1:2:length(tp)-1)
                        tps = tp(2:2:length(tp))
                        tpp = tpp(~isnat(tpp));
                        tps = tps(~isnat(tps));
                        
%                         coordin = coord(~isnat(tpo),:);
                        coordin = Olddates.stations(w);
                        coordinp = coordin.coordp;
                        coordins = coordin.coords;
                        if strcmp(methode,'grid')
                            tic
                            [lon(z),lat(z),h(z),vpges(z),vsges(z),res(z)] = invtest(app,coordinp,coordins,tpp,tps,vprange,vsrange,counter,zrange);
                            toc
                        end
                        if strcmp(methode,'linear')
                            vpges(w) = vprange(1);
                            vsges(w) = vpges(w)/app.vpvsEditField.Value;
                            [lon(z),lat(z),h(z),t(z),error(z),res(z)] = invloc(app,coordinp,coordins,tpp,tps,vprange(1),vsrange(1),zrange);
                        end
                        if strcmp(methode,'genetic')
                            tic
                            [lon(z),lat(z),h(z),t(z),vpges(z),error(z)] = evoloc3app(app,coordinp,coordins,tpp,tps,zrange,vprange);
                            toc
                        end
                        app.UITable2.Data = [lat',lon',h'];
                        pause(0.1)
                        lat_hypo =lat(z);
                        lon_hypo =lon(z);
                        alt_hypo =h(z);
                        Residuum = res(z);
                        v = vpges(z);
                        v2 =vsges(z);
                        Time = Olddates.Time(w);
                        Arrivaltimes_1 = tpo(1);
                        Arrivaltimes_2 = tpo(2);
                        Arrivaltimes_3 = tpo(3);
                        Arrivaltimes_4 = tpo(4);
                        Arrivaltimes_5 = tpo(5);
                        Arrivaltimes_6 = tpo(6);
                        Arrivaltimes_7 = tpo(7);
                        Arrivaltimes_8 = tpo(8);
                        Arrivaltimes_9 = tpo(9);
                        Arrivaltimes_10 = tpo(10);
                        EvType = Olddates.EvType(w);
                        signal = Olddates.signal(w);
                        stations.coordp = coordinp;
                        stations.coords = coordins;
                        entrie = table(Time,Arrivaltimes_1,Arrivaltimes_2,...
                            Arrivaltimes_3,Arrivaltimes_4,Arrivaltimes_5,...
                            Arrivaltimes_6,Arrivaltimes_7,Arrivaltimes_8,...
                            Arrivaltimes_9,Arrivaltimes_10,...
                            lat_hypo,lon_hypo,alt_hypo,v,v2,EvType,signal,stations,Residuum)
                        
    %                     entrie = table(tpo(1),tpo(2),...
    %                         tpo(3),tpo(4),tpo(5),...
    %                         lat_hypo,lon_hypo,alt_hypo,v,Olddates.EvType(w),Olddates.signal(w))
                        Olddates2 = [Olddates2;entrie];
                        
    %                                             if Oldexisted
    %                                                delete('Olddates.csv')
    %                                             end
    %                                             writetable(Olddates,'Olddates.csv');
                        Data.Olddates = Olddates2;
                        save(oldnamewritefile,'Data');
                        
                        counter = counter+1;
                        z = z+1;
                    elseif strcmp(app.CategoryEditField.Value,'all') 
                        tpo = [Olddates.Arrivaltimes_1(w),Olddates.Arrivaltimes_2(w),...
                            Olddates.Arrivaltimes_3(w),Olddates.Arrivaltimes_4(w),...
                            Olddates.Arrivaltimes_5(w),Olddates.Arrivaltimes_6(w),...
                            Olddates.Arrivaltimes_7(w),Olddates.Arrivaltimes_8(w),...
                            Olddates.Arrivaltimes_9(w),Olddates.Arrivaltimes_10(w)]
                        tp = tpo;
                        tpp = tp(1:2:length(tp)-1)
                        tps = tp(2:2:length(tp))
                        tpp = tpp(~isnat(tpp));
                        tps = tps(~isnat(tps));
                        coordin = Olddates.stations(w);
                        coordinp = coordin.coordp;
                        coordins = coordin.coords;
                        if strcmp(methode,'grid')
                            tic
                            [lon(z),lat(z),h(z),vpges(z),vsges(z),res(z)] = invtest(app,coordinp,coordins,tpp,tps,vprange,vsrange,counter,zrange);
                            toc
                        end
                        if strcmp(methode,'linear')
                            vpges(w) = vprange(1);
                            vsges(w) = vpges(w)/app.vpvsEditField.Value;
                            [lon(z),lat(z),h(z),t(z),error(z),res(z)] = invloc(app,coordinp,coordins,tpp,tps,vprange(1),vsrange(1),zrange);
                        end
                        if strcmp(methode,'genetic')
                            tic
                            [lon(z),lat(z),h(z),t(z),vpges(z),error(z)] = evoloc3app(app,coordinp,coordins,tpp,tps,zrange,vprange);
                            toc
                        end
                        app.UITable2.Data = [lat',lon',h'];
                        pause(0.1)
                        lat_hypo =lat(z);
                        lon_hypo =lon(z);
                        alt_hypo =h(z);
                        Residuum = res(z);
                        v = vpges(z);
                        v2 = vsges(z);
                        Time = Olddates.Time(w);
                        Arrivaltimes_1 = tpo(1);
                        Arrivaltimes_2 = tpo(2);
                        Arrivaltimes_3 = tpo(3);
                        Arrivaltimes_4 = tpo(4);
                        Arrivaltimes_5 = tpo(5);
                        Arrivaltimes_6 = tpo(6);
                        Arrivaltimes_7 = tpo(7);
                        Arrivaltimes_8 = tpo(8);
                        Arrivaltimes_9 = tpo(9);
                        Arrivaltimes_10 = tpo(10);
                        EvType = Olddates.EvType(w);
                        signal = Olddates.signal(w);
                        stations.coordp = coordinp;
                        stations.coords = coordins;
                        entrie = table(Time,Arrivaltimes_1,Arrivaltimes_2,...
                            Arrivaltimes_3,Arrivaltimes_4,Arrivaltimes_5,...
                            Arrivaltimes_6,Arrivaltimes_7,Arrivaltimes_8,...
                            Arrivaltimes_9,Arrivaltimes_10,...
                            lat_hypo,lon_hypo,alt_hypo,v,v2,EvType,signal,stations,Residuum)
                        Olddates2 = [Olddates2;entrie];
                        Data.Olddates = Olddates2;
                        save(oldnamewritefile,'Data');
                        
                        counter = counter+1;
                        z = z+1;
                    end
                end
            end
            if app.ShowjustknowneventsCheckBox.Value
                
            end
            if app.RelocateknowneventsCheckBox.Value && app.withpickingCheckBox.Value
                
                for w = 2:length(Olddates.Time)
                    %% Show Progress
                    app.ProgressGauge.Value = 100/length(Olddates.Time)*w;
                    pause(0.1)
                    if strcmp(Olddates.EvType(w),app.CategoryEditField.Value)
                    [tp,button,EvType,~] = plotSnippet6(app,Olddates.signal(w));
                    if max(button) == 1
                    EvType = string(EvType);
                    tp = datetime(tp,'ConvertFrom','datenum')';
                    if max(button) == 1
                        tpo = tp
                        tpp = tp(1:2:length(tp)-1)  
                        tps = tp(2:2:length(tp))
                        buttonp = button(1:2:length(tp)-1)
                        buttons = button(2:2:length(tp))
                        tpp = tpp(logical(buttonp))
                        tps = tps(logical(buttons))
                        sumbutton = buttonp +buttons
                        coordinp = coord(logical(buttonp),:);
                        coordins = coord(logical(buttons),:);
                    end
                        if strcmp(methode,'grid')
                            tic
                            [lon(z),lat(z),h(z),vpges(z),vsges(z),res(z)] = invtest(app,coordinp,coordins,tpp,tps,vprange,vsrange,counter,zrange);
                            toc
                        end
                        if strcmp(methode,'linear')
                            vpges(w) = 8000;
                            [lon(z),lat(z),h(z),t(z),error(z),res(z)] = invloc(app,coordinp,coordins,tpp,tps,vprange(1),vsrange(1),zrange);
                        end
                        if strcmp(methode,'genetic')
                            tic
                            [lon(z),lat(z),h(z),t(z),vpges(z),error(z)] = evoloc3app(app,coordinp,coordins,tpp,tps,zrange,vprange);
                            toc
                        end
                        app.UITable2.Data = [lat',lon',h'];
                        pause(0.1)
                        lat_hypo =lat(z);
                        lon_hypo =lon(z);
                        alt_hypo =h(z);
                        Residuum = res(z);
                        v = vpges(z)
                        Time = Olddates.Time(w);
                        Arrivaltimes_1 = tpo(1);
                        Arrivaltimes_2 = tpo(2);
                        Arrivaltimes_3 = tpo(3);
                        Arrivaltimes_4 = tpo(4);
                        Arrivaltimes_5 = tpo(5);
                        Arrivaltimes_6 = tpo(6);
                        Arrivaltimes_7 = tpo(7);
                        Arrivaltimes_8 = tpo(8);
                        Arrivaltimes_9 = tpo(9);
                        Arrivaltimes_10 = tpo(10);
                        EvType = Olddates.EvType(w);
                        signal = Olddates.signal(w);
                        stations.coordp = coordinp;
                        stations.coords = coordins;
                        entrie = table(Time,Arrivaltimes_1,Arrivaltimes_2,...
                            Arrivaltimes_3,Arrivaltimes_4,Arrivaltimes_5,...
                            Arrivaltimes_6,Arrivaltimes_7,Arrivaltimes_8,...
                            Arrivaltimes_9,Arrivaltimes_10,...
                            lat_hypo,lon_hypo,alt_hypo,v,EvType,signal,stations,Residuum)
                        Olddates2 = [Olddates2;entrie];
                        Data.Olddates = Olddates2;
                        save(oldnamewritefile,'Data');
                        
                        counter = counter+1;
                        z = z+1;
                    elseif strcmp(app.CategoryEditField.Value,'all') 
                        tpo = [Olddates.Arrivaltimes_1(w),Olddates.Arrivaltimes_2(w),...
                            Olddates.Arrivaltimes_3(w),Olddates.Arrivaltimes_4(w),...
                            Olddates.Arrivaltimes_5(w),Olddates.Arrivaltimes_6(w),...
                            Olddates.Arrivaltimes_7(w),Olddates.Arrivaltimes_8(w),...
                            Olddates.Arrivaltimes_9(w),Olddates.Arrivaltimes_10(w)]
                        tp = tpo;
                        tpp = tp(1:2:length(tp)-1)
                        tps = tp(2:2:length(tp))
                        tpp = tpp(~isnat(tpp));
                        tps = tps(~isnat(tps));
                        coordin = Olddates.stations(w);
                        coordinp = coordin.coordp;
                        coordins = coordin.coords;
                        if strcmp(methode,'grid')
                            tic
                            [lon(z),lat(z),h(z),vpges(z),vsges(z),res(z)] = invtest(app,coordinp,coordins,tpp,tps,vprange,vsrange,counter,zrange);
                            toc
                        end
                        if strcmp(methode,'linear')
                            vpges(w) = vprange(1);
                            vsges(w) = vpges(w)/app.vpvsEditField.Value;
                            [lon(z),lat(z),h(z),t(z),error(z)] = invloc(app,coordinp,coordins,tpp,tps,vprange(1),vsrange(1),zrange);
                        end
                        if strcmp(methode,'genetic')
                            tic
                            [lon(z),lat(z),h(z),t(z),vpges(z),error(z)] = evoloc3app(app,coordinp,coordins,tpp,tps,zrange,vprange);
                            toc
                        end
                        app.UITable2.Data = [lat',lon',h'];
                        pause(0.1)
                        lat_hypo =lat(z);
                        lon_hypo =lon(z);
                        alt_hypo =h(z);
                        Residuum = res(z);
                        v = vpges(z);
                        v2 = vsges(z);
                        Time = Olddates.Time(w);
                        Arrivaltimes_1 = tpo(1);
                        Arrivaltimes_2 = tpo(2);
                        Arrivaltimes_3 = tpo(3);
                        Arrivaltimes_4 = tpo(4);
                        Arrivaltimes_5 = tpo(5);
                        Arrivaltimes_6 = tpo(6);
                        Arrivaltimes_7 = tpo(7);
                        Arrivaltimes_8 = tpo(8);
                        Arrivaltimes_9 = tpo(9);
                        Arrivaltimes_10 = tpo(10);
                        EvType = Olddates.EvType(w);
                        signal = Olddates.signal(w);
                        stations.coordp = coordinp;
                        stations.coords = coordins;
                        entrie = table(Time,Arrivaltimes_1,Arrivaltimes_2,...
                            Arrivaltimes_3,Arrivaltimes_4,Arrivaltimes_5,...
                            Arrivaltimes_6,Arrivaltimes_7,Arrivaltimes_8,...
                            Arrivaltimes_9,Arrivaltimes_10,...
                            lat_hypo,lon_hypo,alt_hypo,v,v2,EvType,signal,stations,Residuum)
                        Olddates2 = [Olddates2;entrie];
                        Data.Olddates = Olddates2;
                        save(oldnamewritefile,'Data');
                        
                        counter = counter+1;
                        z = z+1;
                    end
                    end
                end
            end
            if exist('lat','var')
            
                %% Show locations on map figure
                figure(counter)
                axesm ('mercator','MapLatLimit',[min(min(lat))-1 max(max(lat))+1],'MapLonLimit',[min(min(lon))-1 max(max(lon))+1])
                filename = gunzip('gshhs_f.b.gz');
                shorelines = gshhs(filename{1},[min(min(lat))-1 max(max(lat))+1],[min(min(lon))-1 max(max(lon))+1]);
                geoshow([shorelines.Lat],[shorelines.Lon])
                levels = [shorelines.Level];
                land = (levels == 1);
                lake = (levels == 2);
                island = (levels == 3);
                worldmap([min(min(lat))-1 max(max(lat))+1],[min(min(lon))-1 max(max(lon))+1])
                geoshow(shorelines(land),  'FaceColor',[0 1 0])
                geoshow(shorelines(lake),  'FaceColor',[0 0 1])
                geoshow(shorelines(island),'FaceColor',[1 1 0])
                if length(coord)>1
                coordinates = geopoint(coord(:,2),coord(:,1));
                geoshow(coordinates.Latitude,coordinates.Longitude,'Marker','square',...
                    'MarkerSize',2,'LineStyle','none','Color','black','MarkerFaceColor','black')
                end
                hypos = geopoint(lat,lon);
                geoshow(hypos.Latitude,hypos.Longitude,'Marker','+',...
                    'MarkerSize',4,'LineStyle','none','MarkerFaceColor','red','Color','red')
                setm(gca, 'FFaceColor', 'cyan')
                xlabel('Longitude')
                ylabel('Latitude')
                %% Earthquake density plot
                if length(lat)>1 && app.plotdensityofEventsCheckBox.Value
                    [ed,Cx,Cy] = eqdensity(app,lat,lon,60,coord);
                    if length(coord)>1
                        [latx,lonx,zx] = enu2geodetic(Cx,Cy,0,...
                            coord(1,2),coord(1,1),0,wgs84Ellipsoid);
                    else
                        [latx,lonx,zx] = enu2geodetic(Cx,Cy,0,...
                        lat(1),lon(1),0,wgs84Ellipsoid);
                    end
                    figure(counter+1)
                    axesm ('mercator','MapLatLimit',...
                        [min(min(latx)) max(max(latx))],'MapLonLimit',[min(min(lonx)) max(max(lonx))])
                    contourfm(latx,lonx,ed);
                    filename = gunzip('gshhs_f.b.gz');
                    shorelines = gshhs(filename{1});
                    plotm([shorelines.Lat], [shorelines.Lon],'black','LineWidth',1) 
                    if length(coord)>1
                    plotm(coord(:,2),coord(:,1),'ks','MarkerSize',3,'MarkerFaceColor','black')
                    end
                    xlabel('Longitude')
                    ylabel('Latitude')
                    title('Erdbebendichte')
                end
            end
        end
        function [tp,button,EvType,signal] = plotSnippet5(app,D,dates,time)
            %%
            names = fieldnames(D);
            statnum = numel(names);
            dnames =string(datetime(dates,'Format','dd-MM-yyyy hh-mm-ss'));
            for i=1:statnum
                for j = 1:length(dates)
                    [tn(i,:),dn(i,:)] = cut(app,dates(j),D.(names{i}).t,D.(names{i}).d);
                    f = figure(j);
                    subplot(statnum,1,i)
                    yyaxis left
                    plot(tn(i,:),dn(i,:))
                    hold on
                    [~,~,tline] = spectrum(app,tn(i,:),dn(i,:));
                    for linecount = 1:length(tline)
                        xline(tline(linecount),'r');
                    end
                    yyaxis left
                    hold off
                    if i == statnum
                        f.WindowState = 'maximized';
                        Chcomp = 'nothing';
                        while ~strcmp(Chcomp,'n')
                            Chcomp=string(inputdlg('Change component?'));
                            comp = str2num(extractAfter(Chcomp,1));
                            pl = str2num(extractBefore(Chcomp,2));
                            if ~strcmp(Chcomp,'n')
                                if comp == 0
                                    tc = tn(pl,:);
                                    dc = dn(pl,:);
                                    [~,~,tline] = spectrum(app,tc,dc);
                                elseif comp ==1
                                    direct = char(strjoin([pwd,'\data-in1\', names{pl},'\',string(replace(D.(names{pl}).file,'pri0','pri1'))],''));
                                    X = rdmseed(direct);
                                    D.(names{pl}).t1 = cat(1,X.t);
                                    D.(names{pl}).d1 = cat(1,X.d);
                                    [tc,dc] = cut(app,dates(j),D.(names{pl}).t1,D.(names{pl}).d1);
                                    [~,~,tline] = spectrum(app,tc',dc');
                                elseif comp ==2
                                    direct = char(strjoin([pwd,'\data-in2\', names{pl},'\',string(replace(D.(names{pl}).file,'pri0','pri2'))],''));
                                    X = rdmseed(direct);
                                    D.(names{pl}).t2 = cat(1,X.t);
                                    D.(names{pl}).d2 = cat(1,X.d);
                                    [tc,dc] = cut(app,dates(j),D.(names{pl}).t2,D.(names{pl}).d2);
                                    [~,~,tline] = spectrum(app,tc',dc');
                                end
                                subplot(statnum,1,pl)
                                plot(tc,dc)
                                for linecount = 1:length(tline)
                                    xline(tline(linecount),'r');
                                end
                            end
                        end
                        
                        
                        signal.tn = tn;
                        signal.dn = dn;
                        EvType = [];
                        EvType=inputdlg('Event type:');
                        waitfor(EvType)
                        if ~strcmp(EvType,'n')
                            zoom on
                            waitfor(gcf, 'CurrentCharacter', char(13))
                            zoom reset
                            zoom off
                            [tp,~,button] = ginput(2*statnum);
                            button(button==3)=0;
                        else
                            tp = [];
                            button = 100;
                        end
                        if max(button) ~= 1
                            sname = join([pwd,'\Plots\Bad\',dnames{j},'.jpg']);
                            if ~strcmp(EvType,'n')
                                saveas(figure(j),sname);
                            end
                            close(figure(j))
                        else
                            tn = datetime(tn,'ConvertFrom','datenum');
                            close(figure(j))
                            counter = 1;
                            button1 = button(1:2:length(button)-1);
                            button2 = button(2:2:length(button));
                            tpcut = tp(1:2:length(button)-1);
                            sumbutton = button1+button2;
                            sumbutton(sumbutton > 1) = 1;
                            [~,I] = min(tpcut);
                            [~,pos]=min(abs(datenum(tn(I,:))-tpcut(I)));
                            for k = 1:statnum
                                if logical(sumbutton(k)) 
                                    subplot(sum(sumbutton),1,counter)
                                    
                                    plot(tn(k,pos-500:end),dn(k,pos-500:end))
                                    title(names{k})
                                    counter = counter+1;
                                end
                            end
                            hold on
                            counter = 1;
                            
                            for k = 1:length(button)
                                if logical(mod(k,2))
                                    Flag = false;
                                end
                                if logical(button(k)) && logical(mod(k,2))
                                    subplot(sum(sumbutton),1,counter)
                                    xline(datetime(tp(k),'ConvertFrom','datenum'),'k');
                                    Flag = true;
                                elseif logical(button(k)) && ~logical(mod(k,2))
                                    subplot(sum(sumbutton),1,counter)
                                    xline(datetime(tp(k),'ConvertFrom','datenum'),'r');
                                    Flag = true;
                                end
                                if ~logical(mod(k,2)) && Flag
                                    counter = counter+1;
                                end
                            end
                            hold off
                            if ~strcmp(EvType,'n')
                                sname = join([pwd,'\Plots\',dnames{j},'.jpg']);
                                saveas(figure(j),sname);
                            end
                            close(figure(j))
                        end
                    end
                end
            end
        end
        function [tp,button,EvType,signal] = plotSnippet6(app,signal)
            %%
            
            tn = cat(1,signal.tn);
            dn =cat(1,signal.dn);
            statnum = size(tn);
            statnum = statnum(1);
            for i=1:statnum
                for j = 1:1
                    f = figure(j);
                    subplot(statnum,1,i)
                    yyaxis left
                    plot(tn(i,:),dn(i,:))
                    hold on
                    [~,~,tline] = spectrum(app,tn(i,:),dn(i,:));
%                     yyaxis right
%                     plot(ts,ds)
                    for linecount = 1:length(tline)
                        xline(tline(linecount),'r');
                    end
                    yyaxis left
                    hold off
        %             xline(time(j,i))
                    if i == statnum
                        f.WindowState = 'maximized';
                        Chcomp = 'nothing';
                        while ~strcmp(Chcomp,'n')
                            Chcomp=string(inputdlg('Change component?'));
%                             waitfor(Chcomp)
                            comp = str2num(extractAfter(Chcomp,1));
                            pl = str2num(extractBefore(Chcomp,2));
                            if ~strcmp(Chcomp,'n')
                                if comp == 0
                                    tc = tn(pl,:);
                                    dc = dn(pl,:);
                                    [~,~,tline] = spectrum(app,tc,dc);
                                elseif comp ==1
                                    direct = char(strjoin([pwd,'\data-in1\', names{pl},'\',string(replace(D.(names{pl}).file,'pri0','pri1'))],''));
                                    X = rdmseed(direct);
                                    D.(names{pl}).t1 = cat(1,X.t);
                                    D.(names{pl}).d1 = cat(1,X.d);
                                    [tc,dc] = cut(app,dates(j),D.(names{pl}).t1,D.(names{pl}).d1);
                                    [~,~,tline] = spectrum(app,tc',dc');
                                elseif comp ==2
                                    direct = char(strjoin([pwd,'\data-in2\', names{pl},'\',string(replace(D.(names{pl}).file,'pri0','pri2'))],''));
                                    X = rdmseed(direct);
                                    D.(names{pl}).t2 = cat(1,X.t);
                                    D.(names{pl}).d2 = cat(1,X.d);
                                    [tc,dc] = cut(app,dates(j),D.(names{pl}).t2,D.(names{pl}).d2);
                                    [~,~,tline] = spectrum(app,tc',dc');
                                end
                                subplot(statnum,1,pl)
                                plot(tc,dc)
                                for linecount = 1:length(tline)
                                    xline(tline(linecount),'r');
                                end
                            end
                        end
                        
                        
                        signal.tn = tn;
                        signal.dn = dn;
                        EvType = [];
                        EvType=inputdlg('Event type:');
                        waitfor(EvType)
%                         f.WindowState = 'fullscreen';
                        if ~strcmp(EvType,'n')
                            zoom on
                            waitfor(gcf, 'CurrentCharacter', char(13))
                            zoom reset
                            zoom off
                            [tp,~,button] = ginput(2*statnum);
                            button(button==3)=0;
                        else
                            tp = [];
                            button = 100;
                        end
                        if max(button) ~= 1
                            if ~strcmp(EvType,'n')
                                sname = join([pwd,'\Plots\Bad\',dnames{j},'.jpg']);
                                saveas(figure(j),sname);
                            end
                            close(figure(j))
                        else
                            tn = datetime(tn,'ConvertFrom','datenum');
                            close(figure(j))
                            counter = 1;
                            button1 = button(1:2:length(button)-1);
                            button2 = button(2:2:length(button));
                            tpcut = tp(1:2:length(button)-1);
                            sumbutton = button1+button2;
                            sumbutton(sumbutton > 1) = 1;
                            [~,I] = min(tpcut);
                            [~,pos]=min(abs(datenum(tn(I,:))-tpcut(I)));
                            for k = 1:statnum
                                if logical(sumbutton(k)) 
                                    subplot(sum(sumbutton),1,counter)
                                    
                                    plot(tn(k,pos-500:end),dn(k,pos-500:end))
                                    
                                    counter = counter+1;
                                end
                            end
                            hold on
                            counter = 1;
                            
                            for k = 1:length(button)
                                if logical(mod(k,2))
                                    Flag = false;
                                end
                                if logical(button(k)) && logical(mod(k,2))
                                    subplot(sum(sumbutton),1,counter)
                                    xline(datetime(tp(k),'ConvertFrom','datenum'),'k');
                                    Flag = true;
                                elseif logical(button(k)) && ~logical(mod(k,2))
                                    subplot(sum(sumbutton),1,counter)
                                    xline(datetime(tp(k),'ConvertFrom','datenum'),'r');
                                    Flag = true;
                                end
                                if ~logical(mod(k,2)) && Flag
                                    counter = counter+1;
                                end
                            end
                            hold off
                            if ~strcmp(EvType,'n')
                                sname = join([pwd,'\Plots\',dnames{j},'.jpg']);
                                saveas(figure(j),sname);
                            end
                            close(figure(j))
                        end
                    end
                end
            end
        end
        function [lon,lat,z,t,v,error] = evoloc3app(app,coordp,coords,pstartp,pstarts,zrange,vrange)
            perccross = 40;
            percmut = 40;
            numind = 100;
            %% read station coordinates as xyz
%             disp(coord)
            [xstatp,ystatp,zstatp] = geodetic2enu(coordp(:,2),coordp(:,1),coordp(:,3)...
                ,coordp(1,2),coordp(1,1),0,wgs84Ellipsoid);
            xstatp = [xstatp,ystatp,-zstatp];
            numstatp = length(ystatp);
            
            [xstats,ystats,zstats] = geodetic2enu(coords(:,2),coords(:,1),coords(:,3)...
                ,14.745106,-24.700367,0,wgs84Ellipsoid);
            xstats = [xstats,ystats,-zstats];
            numstats = length(ystats);
            
            
            %% Calculate time differences
            
            m = min(pstartp);
            newtimes = seconds(pstartp - m);
            secp = newtimes+10;
            
            newtimes = seconds(pstarts - m);
            secs = newtimes+10;
            
            %% Initiate new Population
            
            meanx = mean(xstatp(:,1));
            meany = mean(xstatp(:,2));
            
            for i = 1:numind
                Population(i,:) = [randi([(round(meanx-100000)),(round(meanx+100000))]),...
                    randi([(round(meany-100000)),(round(meany+100000))]),randi([zrange(1),zrange(3)]),...
                    randi([0,1000])/100,randi([vrange(1),vrange(3)])];
            end
            
            %% Optimization
            
            a = 1;
            stand = [1e100];
            fehler = [];
            
            while a < 3000
                % Calculate squares of time differences between data and model
                for i = 1:numind
                    dp(:,i) = 1/Population(i,5)*((ones(numstatp,1).*Population(i,1)-xstatp(:,1)).^2+(ones(numstatp,1).*...
                        Population(i,2)-xstatp(:,2)).^2+(ones(numstatp,1).*Population(i,3)-xstatp(:,3)).^2).^(1/2)...
                        +Population(i,4);
            %         dif(:,i) = sqrt((d(:,i)-sec').^2);
                    difp(:,i) = (dp(:,i)-secp');
                    if numstats ~=0
                        ds(:,i) = 1/(Population(i,5)/app.vpvsEditField.Value)*((ones(numstats,1).*Population(i,1)-xstats(:,1)).^2+(ones(numstats,1).*...
                            Population(i,2)-xstats(:,2)).^2+(ones(numstats,1).*Population(i,3)-xstats(:,3)).^2).^(1/2)...
                            +Population(i,4);
                %         dif(:,i) = sqrt((d(:,i)-sec').^2);
                        difs(:,i) = (ds(:,i)-secs');
                    end
                      
                end
                if numstats ~= 0
                    dif = [difs;difp];
                else
                    dif = difp;
                end
                
                % Ranking the models

                for i = 1:numind
                    lsq(i) = norm(dif(:,i));
                end
            
                [~,I] = sort(lsq);
                
                if min(lsq)< min(stand)
                    bestindividual = Population(I(1),:);
                    dpbest = dp(:,I(1));
                    if numstats ~= 0
                        dsbest = ds(:,I(1));
                    end
                end
                
                if min(lsq)< min(stand)
                    stand(a) = min(lsq);
                else
                    stand(a) = stand(a-1);
                end
                
            %     if min(lsq)<0.05
            %         break
            %     end
            
                % Creating new generation of Individuals (Population)
                numcross = round(numind*(perccross/100));
                for i = 1:numcross
                    newpopulation(i,:)= [Population(I(randi([1,numcross])),1),Population(I(randi([1,numcross])),2),...
                            Population(I(randi([1,numcross])),3),Population(I(randi([1,numcross])),4),...
                            Population(I(randi([1,numcross])),5)];
                end
                nummut = round(numind*(percmut/100));
                for i = numcross+1:nummut
                newpopulation(i,:) = Population(I(randi([1,numcross])),:);
                randpos = randi([1,5]);
                if randpos == 1
                    newpopulation(i,randpos) = randi([(round(meanx-100000)),(round(meanx+100000))]);
                elseif randpos == 2
                    newpopulation(i,randpos) = randi([(round(meany-100000)),(round(meany+100000))]);
                elseif randpos == 3
                    newpopulation(i,randpos) = randi([zrange(1),zrange(3)]);
                elseif randpos == 4
                    newpopulation(i,randpos) = randi([0,1000])/100;
                elseif randpos == 5
                    newpopulation(i,randpos) = randi([vrange(1),vrange(3)]);
                end
                end
               
                for i = nummut+1:numind
                    newpopulation(i,:) = [randi([(round(meanx-100000)),(round(meanx+100000))]),...
                        randi([(round(meany-100000)),(round(meany+100000))]),randi([zrange(1),zrange(3)]),...
                        randi([0,1000])/100,randi([vrange(1),vrange(3)])];
                end
                a = a+1;
                Population = newpopulation;
            end
            figure(1)
            plot(stand/numstatp)
            dname =string(datetime(pstartp(1),'Format','dd-MM-yyyy hh-mm-ss'));
            saveas(figure(1),strjoin([pwd,'\Plots\',dname,'_Lernkurve.jpg'],''));
            close(figure(1))
            [lat,lon,z] = enu2geodetic(bestindividual(1),bestindividual(2),bestindividual(3),...
                coordp(1,2),coordp(1,1),0,wgs84Ellipsoid);
            error = min(lsq);
            
            t = bestindividual(4);
            v = bestindividual(5);
            %%
            figure(2)
            plot(1:numstatp,dpbest-10,'-ro')
            hold on
            plot(1:numstatp,secp-10,'-bo')
            hold off
            saveas(figure(2),strjoin([pwd,'\Plots\',dname,'_Fit_Evo_p.jpg'],''));
            close(figure(2))
            if numstats ~= 0
                figure(3)
                plot(1:numstats,dsbest-10,'-ro')
                hold on
                plot(1:numstats,secs-10,'-bo')
                hold off
                saveas(figure(2),strjoin([pwd,'\Plots\',dname,'_Fit_Evo_s.jpg'],''));
                close(figure(3))
            end
%             plot(app.UIAxes,1:numstatp,dpbest-10,'-ro')
%             hold(app.UIAxes,'on')
%             plot(app.UIAxes,1:numstatp,secp-10,'-bo')
%             hold(app.UIAxes,'off')
            
        end
        function [ed,Cx,Cy] = eqdensity(app,lat,lon,sigma,coord)
%%
            clear ed
            try
                [x,y,z] = geodetic2enu(lat,lon,zeros(length(lat),1)'...
                     ,coord(1,2),coord(1,1),0,wgs84Ellipsoid);
            catch
                [x,y,z] = geodetic2enu(lat,lon,zeros(length(lat),1)'...
                 ,lat(1),lon(1),0,wgs84Ellipsoid);
            end
             size = [round(abs((max(x)-min(x)))/500),round(abs((max(y)-min(y)))/500)];
             [Cx,Cy] = ndgrid(1:size(1), 1:size(2));
             for i = 1:length(x)
             ed(:,:,i) = gaussC(app,Cx,Cy, sigma, [round(abs(x(i)-min(x)))/500,round(abs(y(i)-min(y)))/500]);
             end
             ed = sum(ed,3);
             Cx = Cx*500+min(x);
             Cy = Cy*500+min(y);
             %%
        end
        function val = gaussC(app,x, y, sigma, center)
            xc = center(1);
            yc = center(2);
            exponent = ((x-xc).^2 + (y-yc).^2)./(2*sigma);
            val       = (exp(-exponent));
        end
        function [lon,lat,z,vpbest,vsbest,res] = invtest(app,coordp,coords,pstartp,pstarts,vprange,vsrange,counter,zrange)

            %% read station coordinates as xyz

            [xstatp,ystatp,zstatp] = geodetic2enu(coordp(:,2),coordp(:,1),coordp(:,3)...
                 ,coordp(1,2),coordp(1,1),0,wgs84Ellipsoid);
            zstatp = -zstatp;
            
            [xstats,ystats,zstats] = geodetic2enu(coords(:,2),coords(:,1),coords(:,3)...
                 ,coordp(1,2),coordp(1,1),0,wgs84Ellipsoid);
            zstats = -zstats;
            
            lp = length(xstatp);
            ls = length(xstats);
            %% Calculate time differences for p-arrivals
            
            m = min(pstartp);
            newtimes = seconds(pstartp - m);
            secp = newtimes+10;
            Depth = app.ThicknesCrustEditField.Value;

            %% Calculate time differences for s-arrivals

            newtimes = seconds(pstarts - m);
            secs = newtimes+10;
            
            %%
            xr = xstatp(1)-100000:1000:xstatp(1)+100000;
            yr = ystatp(1)-100000:1000:ystatp(1)+100000;
            zr = zrange(1):zrange(2):zrange(3);
            tr = 1:1:10;
            vpr = vprange(1):vprange(2):vprange(3);
            %vpr2= 7500:100:8500;
            vpr2= 8000;
            vsr = vsrange(1):vsrange(2):vsrange(3);
            vsr2= vpr2./1.74;
            norm = app.NormDropDown.Value;
            for r = 1:5
            for i = 1:lp
            [X,Y,Z,T0,XS,YS,ZS,T1,V1,V2] = ndgrid(xr,yr,zr,tr,xstatp(i),ystatp(i),zstatp(i),secp(i),vpr,vpr2);
            
            zfind = Z>Depth;
            V1copy = V1;
            V1(zfind) = (V2(zfind).*(Z(zfind)-Depth)+V1(zfind).*Depth)./Z(zfind);
            sec2 = 1./V1.*sqrt((X-XS).^2+(Y-YS).^2+(Z-ZS).^2)+T0;
            sec2head = ((2*Depth-Z-ZS).*sqrt(1-(V1./V2).^2))./V1+...
                    (sqrt((X-XS).^2+(Y-YS).^2))./V2+T0;
            sec2(sec2>sec2head&Z<=(Depth+500)) = sec2head(sec2>sec2head&Z<=(Depth+500));
            ref = sec2>sec2head;
            if i ==1
                sumref = ref;
            else
                sumref = sumref+ref;
            end
            
            VPC = V1copy;
            VPC2 =V2;
            
            if strcmp(norm,'L1')
                sec2 = abs(T1-sec2);
            elseif strcmp(norm,'L2')
                sec2 = (T1-sec2).^2;
            end
            if i ==1
                Normen = sec2.*0;
            end
            Normen = Normen+sec2;
            
            end
            sumref = sumref>0;
            for i = 1:ls
            [X,Y,Z,T0,XS,YS,ZS,T1,V1,V2] = ndgrid(xr,yr,zr,tr,xstats(i),ystats(i),zstats(i),secs(i),vsr,vsr2);
            zfind = Z>Depth;
            V1copy = V1;
            V1(zfind) = (V2(zfind).*(Z(zfind)-Depth)+V1(zfind).*Depth)./Z(zfind);
            sec2 = 1./V1.*sqrt((X-XS).^2+(Y-YS).^2+(Z-ZS).^2)+T0;
            sec2head = ((2*Depth-Z-ZS).*sqrt(1-(V1./V2).^2))./V1+...
                    (sqrt((X-XS).^2+(Y-YS).^2))./V2+T0;
            sec2(sec2>sec2head) = sec2head(sec2>sec2head);
            
            
            if strcmp(norm,'L1')
                sec2 = abs(T1-sec2);
            elseif strcmp(norm,'L2')
                sec2 = (T1-sec2).^2;
            end
            if i ==1 && lp == 0
                Normen = sec2.*0;
            end
            Normen = Normen+sec2;
            
            end
            if strcmp(norm,'L2')
                Normen = sqrt(Normen);
            end
            [res,pos] = min(Normen,[],'all','linear');
            res = res/(ls+lp);
            Ergebnis = [X(pos),Y(pos),Z(pos),T0(pos),V1copy(pos),VPC(pos),VPC2(pos)*sumref(pos)];
            
            if r ==1
                xr = Ergebnis(1)-50000:500:Ergebnis(1)+50000;
                yr = Ergebnis(2)-50000:500:Ergebnis(2)+50000;
                if zrange(1)~=zrange(3)
                if Ergebnis(3)>=5000
                    zr = Ergebnis(3)-4000:800:Ergebnis(3)+4000;
                else
                    zr = 1000:800:Ergebnis(3)+4000;
                end
                end
                tr = Ergebnis(4)-4:0.8:Ergebnis(4)+4;
                if vprange(1) ~=vprange(3)
                    vpr = Ergebnis(5)-5*vprange(2)/2:5*vprange(2)/2:Ergebnis(5)+5*vprange(2)/2;
                    vsr = Ergebnis(5)-5*vsrange(2)/2:5*vsrange(2)/2:Ergebnis(5)+5*vsrange(2)/2;
                end
            elseif r ==2
                xr = Ergebnis(1)-10000:100:Ergebnis(1)+10000;
                yr = Ergebnis(2)-10000:100:Ergebnis(2)+10000;
                if zrange(1)~=zrange(3)
                if Ergebnis(3)>=3000
                    zr = Ergebnis(3)-2000:400:Ergebnis(3)+2000;
                else
                    zr = 1000:400:Ergebnis(3)+2000;
                end
                end
                tr = Ergebnis(4)-2:0.4:Ergebnis(4)+2;
                if vprange(1) ~=vprange(3)
                    vpr = Ergebnis(5)-5*vprange(2)/4:5*vprange(2)/4:Ergebnis(5)+5*vprange(2)/4;
                    vsr = Ergebnis(5)-5*vsrange(2)/4:5*vsrange(2)/4:Ergebnis(5)+5*vsrange(2)/4;
                end
            elseif r ==3
                xr = Ergebnis(1)-5000:50:Ergebnis(1)+5000;
                yr = Ergebnis(2)-5000:50:Ergebnis(2)+5000;
                if zrange(1)~=zrange(3)
                if Ergebnis(3)>=2000
                    zr = Ergebnis(3)-1000:200:Ergebnis(3)+1000;
                else
                    zr = 1000:200:Ergebnis(3)+1000;
                end
                end
                tr = Ergebnis(4)-1:0.2:Ergebnis(4)+1;
                if vprange(1) ~=vprange(3)
                    vpr = Ergebnis(5)-5*vprange(2)/8:5*vprange(2)/8:Ergebnis(5)+5*vprange(2)/8;
                    vsr = Ergebnis(5)-5*vsrange(2)/8:5*vsrange(2)/8:Ergebnis(5)+5*vsrange(2)/8;
                end

            elseif r ==4
                xr = Ergebnis(1)-1000:10:Ergebnis(1)+1000;
                yr = Ergebnis(2)-1000:10:Ergebnis(2)+1000;
                if zrange(1)~=zrange(3)
                if Ergebnis(3)>=2000
                    zr = Ergebnis(3)-1000:200:Ergebnis(3)+1000;
                else
                    zr = 1000:200:Ergebnis(3)+1000;
                end
                end
                tr = Ergebnis(4)-1:0.1:Ergebnis(4)+1;
                if vprange(1) ~=vprange(3)
                    vpr = Ergebnis(5)-5*vprange(2)/16:5*vprange(2)/16:Ergebnis(5)+5*vprange(2)/16;
                    vsr = Ergebnis(5)-5*vsrange(2)/16:5*vsrange(2)/16:Ergebnis(5)+5*vsrange(2)/16;
                end

            end
            end

            [lat,lon,z] = enu2geodetic(Ergebnis(1),Ergebnis(2),-Ergebnis(3),...
                coordp(1,2),coordp(1,1),0,wgs84Ellipsoid);
            if ls == 0
                vpbest = Ergebnis(5);
                vsbest = vpbest/app.vpvsEditField.Value;
            else
                vsbest = Ergebnis(7);
                vpbest = Ergebnis(6);
            end
        end
        function [lon,lat,z,t,error,olddif] = invloc(app,coordp,coords,pstartp,pstarts,vp,vs,zrange)

            %% read station coordinates as xyz
            
            vs = vp/app.vpvsEditField.Value;
            [xstatp,ystatp,zstatp] = geodetic2enu(coordp(:,2),coordp(:,1),coordp(:,3)...
                 ,coordp(1,2),coordp(1,1),0,wgs84Ellipsoid);
            zstatp = -zstatp;
            lp = length(xstatp);
            
            [xstats,ystats,zstats] = geodetic2enu(coords(:,2),coords(:,1),coords(:,3)...
                 ,coordp(1,2),coordp(1,1),0,wgs84Ellipsoid);
            zstats = -zstats;
            ls = length(xstats);
            
            %% Calculate time differences
            
            m = min(pstartp);
            newtimes = seconds(pstartp - m);
            secp = newtimes+10;
            
%             m = min(pstarts);
            newtimes = seconds(pstarts - m);
            secs = newtimes+10;
            
            %% Initiating start model
            
            meanx = mean(xstatp);
            meany = mean(ystatp);
            if zrange(1) ~= zrange(3)
                model = [xstatp(1),...
                        ystatp(1)...
                        ,randi([zrange(1),zrange(3)]),...
                        randi([0,1000])/100];
                model = model';
            else
                model = [xstatp(1),...
                        ystatp(1)...
                        ,zrange(1),...
                        randi([0,1000])/100];
                model = model';
            end
            %%    
            a = 1;
            olddif = 1000000;
            while a<100
            %% Calculating arrival time of the model
            
            dp = 1/vp*((ones(lp,1).*model(1)-xstatp).^2+(ones(lp,1).*...
                        model(2)-ystatp).^2+(ones(lp,1).*model(3)-zstatp).^2).^(1/2)...
                        +model(4);
            ds = 1/vs*((ones(ls,1).*model(1)-xstats).^2+(ones(ls,1).*...
                        model(2)-ystats).^2+(ones(ls,1).*model(3)-zstats).^2).^(1/2)...
                        +model(4);
            %% Evaluationg the error
            
            difp = secp'-dp;
            difs = secs'-ds;
            dif = [difp;difs];
            mean(abs(dif));
            
%             if mean(abs(dif))<1
%                 break
%             end
            
            %% Optimizing the model
            
            Gp(:,1) = (ones(lp,1).*model(1)-xstatp)./vp.*((ones(lp,1).*model(1)-xstatp).^2+...
                (ones(lp,1).*model(2)-ystatp).^2+(ones(lp,1).*model(3)-zstatp).^2).^(-1/2);
            Gp(:,2) = (ones(lp,1).*model(2)-ystatp)./vp.*((ones(lp,1).*model(1)-xstatp).^2+...
                (ones(lp,1).*model(2)-ystatp).^2+(ones(lp,1).*model(3)-zstatp).^2).^(-1/2);
            if zrange(1) ~= zrange(3)
                Gp(:,3) = (ones(lp,1).*model(3)-zstatp)./vp.*((ones(lp,1).*model(1)-xstatp).^2+...
                    (ones(lp,1).*model(2)-ystatp).^2+(ones(lp,1).*model(3)-zstatp).^2).^(-1/2);
            else
                Gp(:,3) = zeros(lp,1);
            end
            Gp(:,4) = ones(lp,1);
            
            Gs(:,1) = (ones(ls,1).*model(1)-xstats)./vs.*((ones(ls,1).*model(1)-xstats).^2+...
                (ones(ls,1).*model(2)-ystats).^2+(ones(ls,1).*model(3)-zstats).^2).^(-1/2);
            Gs(:,2) = (ones(ls,1).*model(2)-ystats)./vs.*((ones(ls,1).*model(1)-xstats).^2+...
                (ones(ls,1).*model(2)-ystats).^2+(ones(ls,1).*model(3)-zstats).^2).^(-1/2);
            if zrange(1) ~= zrange(3)
                Gs(:,3) = (ones(ls,1).*model(3)-zstats)./vs.*((ones(ls,1).*model(1)-xstats).^2+...
                    (ones(ls,1).*model(2)-ystats).^2+(ones(ls,1).*model(3)-zstats).^2).^(-1/2);
            else
                Gs(:,3) = zeros(ls,1);
            end
            Gs(:,4) = ones(ls,1);
            G = [Gp;Gs];
            [U,S,V] = csvd(G);
            [alpha,rho,eta,reg_param] = l_curve (U,S,dif);
            [dm,rho,eta] = tikhonov(U,S,V,dif,alpha);
            if mean(dif)>olddif
                break
            end
            olddif =mean(dif);
            model2 = model+dm;
            model = model2;
            a = a+1;
            end    
            [lat,lon,z] = enu2geodetic(model(1),model(2),-model(3),...
                coordp(1,2),coordp(1,1),0,wgs84Ellipsoid);
            
            error = norm([secp,secs]-[dp;ds]');
            t = model(4);
            
             %% plot differences
            plot(1:lp,dp-10,'-ro')
            hold on
            plot(1:lp,secp-10,'-bo')
            legend('model','data')
            title('Ankunftszeiten der P-Wellen an den Stationen')
            ylabel('t [s]')
            xlabel('Stationen')
            xticks(1:lp)
            hold off
            dname =string(datetime(pstartp(1),'Format','dd-MM-yyyy hh-mm-ss'));
            saveas(figure(1),strjoin([pwd,'\Plots\',dname,'_p_Fit_linear.jpg'],''));
            
            plot(1:ls,ds-10,'-ro')
            hold on
            plot(1:ls,secs-10,'-bo')
            legend('model','data')
            title('Ankunftszeiten der S-Wellen an den Stationen')
            ylabel('t [s]')
            xlabel('Stationen')
            xticks(1:ls)
            hold off
            dname =string(datetime(pstartp(1),'Format','dd-MM-yyyy hh-mm-ss'));
            saveas(figure(1),strjoin([pwd,'\Plots\',dname,'_s_Fit_linear.jpg'],''));
        end
        function [tnew,dnew] = cut(app,date,t,d)
            date = datenum(date);
            [~,pos]=min(abs(t-date));
            if pos >2000 && pos < (length(t)-2000)
                tnew = t(pos-2000:pos+2000);
                dnew = d(pos-2000:pos+2000);
            elseif pos <2000 && pos < (length(t)-2000)
                tnew = t(pos-10:pos+2000);
                dnew = d(pos-10:pos+2000);
            elseif pos >2000 && pos > (length(t)-2000)
                tnew = t(pos-2000:pos+10);
                dnew = d(pos-2000:pos+10);
            end
        end
        function [t,d,tout] = spectrum(app,tn,dn)
            freq = 100;
            L = 21;
            for i = 1:length(tn)-20
                transform = fft(dn(i:i+20));
                P2 = abs(transform/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = freq*(0:(L/2))/L;
                d(i) = mean(P1.^2.*f)^3;
                t(i) = tn(i+20);
            end
            
            d1 = movmean(d,[1,0]);
            d2 = movmean(d,[20,0]);
            d = d1./d2;
            d(isnan(d)) = 0;
            d(d<2) = 0;
            Flag = true;
            count = 1;
            for i = 1:length(d)
                if d(i)>5 && Flag
                    tout(count) = t(i);
                    count = count+1;
                    Flag = false;
                end
                if d(i)<3 && ~Flag
                    Flag = true;
                end
                
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TriggerEditField
        function TriggerEditFieldValueChanged(app, event)
            Trigger = app.TriggerEditField.Value;
            
        end

        % Value changed function: STAWindowEditField
        function STAWindowEditFieldValueChanged(app, event)
            shortWindowSec = app.STAWindowEditField.Value;
            
        end

        % Value changed function: LTAWindowEditField
        function LTAWindowEditFieldValueChanged(app, event)
            longWindowSec = app.LTAWindowEditField.Value;
            
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            tolSec = app.ToleranceEditField.Value;
            
        end

        % Callback function
        function AnzahlStationenEditFieldValueChanged(app, event)
            statnum = app.AnzahlStationenEditField.Value;
            
        end

        % Value changed function: PlotSTALTACheckBox
        function PlotSTALTACheckBoxValueChanged(app, event)
            plotStaLta_Flag = app.PlotSTALTACheckBox.Value;
            
        end

        % Value changed function: PlotEventsCheckBox
        function PlotEventsCheckBoxValueChanged(app, event)
            plot_Flag = app.PlotEventsCheckBox.Value;
            
        end

        % Value changed function: InversionCheckBox
        function InversionCheckBoxValueChanged(app, event)
            inversion = app.InversionCheckBox.Value;
            
        end

        % Value changed function: InversionMethodeDropDown
        function InversionMethodeDropDownValueChanged(app, event)
            methode = app.InversionMethodeDropDown.Value;
            
        end

        % Drop down opening function: InversionMethodeDropDown
        function InversionMethodeDropDownOpening(app, event)
            
        end

        % Button pushed function: ChooseCoordinateFileButton
        function ChooseCoordinateFileButtonPushed(app, event)
            [name,link] = uigetfile('*.xlsx');
            coord = readtable(join([link,name]));
            coord = coord(:,1:3);
            coord.Properties.VariableNames{1} = 'Latitude';
            coord.Properties.VariableNames{2} = 'Longitude';
            coord.Properties.VariableNames{3} = 'Altitude';
            app.UITable.ColumnName = coord.Properties.VariableNames;
            app.UITable.Data = coord;
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.Lamp.Color = 'green';
            pause(0.1)
            Trigger = app.TriggerEditField.Value;
            shortWindowSec = app.STAWindowEditField.Value;
            longWindowSec = app.LTAWindowEditField.Value;
            tolSec = app.ToleranceEditField.Value;
            plotStaLta_Flag = app.PlotSTALTACheckBox.Value;
            plot_Flag = app.PlotEventsCheckBox.Value;
            inversion = app.InversionCheckBox.Value;
            methode = app.InversionMethodeDropDown.Value;
            if ~app.RelocateknowneventsCheckBox.Value && ~app.ShowjustknowneventsCheckBox.Value
                coord = app.UITable.Data;
                coord = table2array(coord);
            else
                coord = [];
            end
            start = app.StartDateDropDown.Value;
            ende = app.EndDateDropDown.Value;
            main(app,Trigger,shortWindowSec,longWindowSec,tolSec,...
                plotStaLta_Flag,plot_Flag,inversion,methode,coord,start,ende)
            app.Lamp.Color = 'red';
        end

        % Value changed function: StartDateDropDown
        function StartDateDropDownValueChanged(app, event)
            value = app.StartDateDropDown.Value;
            
        end

        % Drop down opening function: StartDateDropDown
        function StartDateDropDownOpening(app, event)
            files = dir('data-in');
            ges = [];
            erg = [];
            folders = {files.name};
            allstat = length(folders)-2;
            oldnum = 0;
            finaldates =[];
            for i = 1:allstat
                direct = join([pwd,'\data-in\', folders{i+2}]);
                names = dir(direct);
                strdates = string({names.name});
                strdates = strdates(3:end);
                for j = 1:length(strdates)
                    singledate = char(strdates(j));
                    singledate = singledate(6:11);
                    year = strcat('20',singledate(1:2));
                    month = singledate(3:4);
                    day = singledate(5:6);
                    singledate = string(strcat(day,'.',month,'.',year));
                    strdates(j) = singledate;
                end
                finaldates = cat(2,finaldates,strdates);
                oldnum = numel(names);
            end
            finaldates = unique(finaldates);
            app.StartDateDropDown.Items = finaldates;
        end

        % Drop down opening function: EndDateDropDown
        function EndDateDropDownOpening(app, event)
            files = dir('data-in');
            ges = [];
            erg = [];
            folders = {files.name};
            allstat = length(folders)-2;
            oldnum = 0;
            finaldates =[];
            for i = 1:allstat
                direct = join([pwd,'\data-in\', folders{i+2}]);
                names = dir(direct);
                strdates = string({names.name});
                strdates = strdates(3:end);
                for j = 1:length(strdates)
                    singledate = char(strdates(j));
                    singledate = singledate(6:11);
                    year = strcat('20',singledate(1:2));
                    month = singledate(3:4);
                    day = singledate(5:6);
                    singledate = string(strcat(day,'.',month,'.',year));
                    strdates(j) = singledate;
                end
                finaldates = cat(2,finaldates,strdates);
                oldnum = numel(names);
            end
            finaldates = unique(finaldates);
            app.EndDateDropDown.Items = finaldates;
        end

        % Value changed function: ShowjustknowneventsCheckBox
        function ShowjustknowneventsCheckBoxValueChanged(app, event)
            value = app.ShowjustknowneventsCheckBox.Value;     
            app.ShowalsoknowneventsCheckBox.Enable = ~value;
            app.ExcludeoldbadeventsCheckBox.Enable = ~value;
            app.PlotEventsCheckBox.Enable = ~value;
            app.PlotSTALTACheckBox.Enable = ~value;
            app.InversionCheckBox.Enable = ~value;
            app.TriggerEditField.Enable = ~value;
            app.TriggerEditFieldLabel.Enable = ~value;
            app.STAWindowEditField.Enable = ~value;
            app.STAWindowEditFieldLabel.Enable = ~value;
            app.LTAWindowEditField.Enable = ~value;
            app.LTAWindowEditFieldLabel.Enable = ~value;
            app.ToleranceEditField.Enable = ~value;
            app.ToleranceEditFieldLabel.Enable = ~value;
            app.StartDateDropDown.Enable = ~value;
            app.StartDateDropDownLabel.Enable = ~value;
            app.EndDateDropDown.Enable = ~value;
            app.EndDateDropDownLabel.Enable = ~value;
            app.CategoryEditField.Enable = value;
            app.CategoryEditFieldLabel.Enable = value;
            app.startzEditField.Enable = ~value;
            app.startzEditFieldLabel.Enable = ~value;
            app.endzEditField.Enable = ~value;
            app.endzEditFieldLabel.Enable = ~value;
            app.startvEditField.Enable = ~value;
            app.startvEditFieldLabel.Enable = ~value;
            app.endvEditField.Enable = ~value;
            app.endvEditFieldLabel.Enable = ~value;
            app.NormDropDown.Enable = ~value;
            app.NormDropDownLabel.Enable = ~value;
            app.InversionMethodeDropDown.Enable = ~value;
            app.InversionMethodeDropDownLabel.Enable = ~value;
            app.vpvsEditField.Enable = ~value;
            app.vpvsEditFieldLabel.Enable = ~value;
            app.ThicknesCrustEditField.Enable = ~value;
            app.ThicknesCrustEditFieldLabel.Enable = ~value;
            app.stepvEditField.Enable = ~value;
            app.stepvEditFieldLabel.Enable = ~value;
            app.stepzEditField.Enable = ~value;
            app.stepzEditFieldLabel.Enable = ~value;
            app.ChooseCoordinateFileButton.Enable = ~value;
            if ~value
                app.UITable.Enable = 'on';
            else
                app.UITable.Enable = 'off';
            
            end
            app.RelocateknowneventsCheckBox.Enable = ~value;
            app.withpickingCheckBox.Enable = ~value;
        end

        % Value changed function: RelocateknowneventsCheckBox
        function RelocateknowneventsCheckBoxValueChanged(app, event)
            value = app.RelocateknowneventsCheckBox.Value;
            app.ShowalsoknowneventsCheckBox.Enable = ~value;
            app.ExcludeoldbadeventsCheckBox.Enable = ~value;
            app.PlotEventsCheckBox.Enable = ~value;
            app.PlotSTALTACheckBox.Enable = ~value;
            app.InversionCheckBox.Enable = ~value;
            app.TriggerEditField.Enable = ~value;
            app.TriggerEditFieldLabel.Enable = ~value;
            app.STAWindowEditField.Enable = ~value;
            app.STAWindowEditFieldLabel.Enable = ~value;
            app.LTAWindowEditField.Enable = ~value;
            app.LTAWindowEditFieldLabel.Enable = ~value;
            app.ToleranceEditField.Enable = ~value;
            app.ToleranceEditFieldLabel.Enable = ~value;
            app.CategoryEditField.Enable = value;
            app.CategoryEditFieldLabel.Enable = value;
            app.StartDateDropDown.Enable = ~value;
            app.StartDateDropDownLabel.Enable = ~value;
            app.EndDateDropDown.Enable = ~value;
            app.EndDateDropDownLabel.Enable = ~value;
            app.ChooseCoordinateFileButton.Enable = ~value;
            if ~value
                app.UITable.Enable = 'on';
            else
                app.UITable.Enable = 'off';
            
            end
%             app.startzEditField.Enable = ~value;
%             app.endzEditField.Enable = ~value;
%             app.startvEditField.Enable = ~value;
%             app.endvEditField.Enable = ~value;
            app.ShowjustknowneventsCheckBox.Enable = ~value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = 'logo.jpg';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.EventdetectionandlocalisationLabel = uilabel(app.UIFigure);
            app.EventdetectionandlocalisationLabel.Text = 'Event detection and localisation';
            app.ProgressGaugeLabel = uilabel(app.UIFigure);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.UIFigure, 'linear');
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Latitude'; 'Longitude'; 'Altitude'};
            app.UITable2.RowName = {};
            app.Lamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.StartDateDropDownLabel = uilabel(app.DataTab);
            app.StartDateDropDownLabel.Text = 'Start Date';
            app.StartDateDropDown = uidropdown(app.DataTab);
            app.StartDateDropDown.Items = {'Start Date'};
            app.StartDateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @StartDateDropDownOpening, true);
            app.StartDateDropDown.ValueChangedFcn = createCallbackFcn(app, @StartDateDropDownValueChanged, true);
            app.StartDateDropDown.Value = 'Start Date';
            app.EndDateDropDownLabel = uilabel(app.DataTab);
            app.EndDateDropDownLabel.Text = 'End Date';
            app.EndDateDropDown = uidropdown(app.DataTab);
            app.EndDateDropDown.Items = {'End Date'};
            app.EndDateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @EndDateDropDownOpening, true);
            app.EndDateDropDown.Value = 'End Date';
            app.CategoryEditFieldLabel = uilabel(app.DataTab);
            app.CategoryEditFieldLabel.Enable = 'off';
            app.CategoryEditFieldLabel.Text = 'Category';
            app.CategoryEditField = uieditfield(app.DataTab, 'text');
            app.CategoryEditField.Enable = 'off';
            app.CategoryEditField.Value = 'all';
            app.InputnameEditFieldLabel = uilabel(app.DataTab);
            app.InputnameEditFieldLabel.Text = 'Input name';
            app.InputnameEditField = uieditfield(app.DataTab, 'text');
            app.InputnameEditField.Value = 'Input';
            app.OutputnameEditFieldLabel = uilabel(app.DataTab);
            app.OutputnameEditFieldLabel.Text = 'Output name';
            app.OutputnameEditField = uieditfield(app.DataTab, 'text');
            app.OutputnameEditField.Value = 'Output';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ChooseCoordinateFileButton = uibutton(app.DataTab, 'push');
            app.ChooseCoordinateFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCoordinateFileButtonPushed, true);
            app.ChooseCoordinateFileButton.Text = 'Choose Coordinate File';
            app.STALTATab = uitab(app.TabGroup);
            app.STALTATab.Title = 'STA/LTA';
            app.TriggerEditFieldLabel = uilabel(app.STALTATab);
            app.TriggerEditFieldLabel.Text = 'Trigger';
            app.TriggerEditField = uieditfield(app.STALTATab, 'numeric');
            app.TriggerEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerEditFieldValueChanged, true);
            app.TriggerEditField.Value = 2;
            app.STAWindowEditFieldLabel = uilabel(app.STALTATab);
            app.STAWindowEditFieldLabel.Text = 'STA-Window';
            app.STAWindowEditField = uieditfield(app.STALTATab, 'numeric');
            app.STAWindowEditField.ValueChangedFcn = createCallbackFcn(app, @STAWindowEditFieldValueChanged, true);
            app.STAWindowEditField.Value = 0.7;
            app.LTAWindowEditFieldLabel = uilabel(app.STALTATab);
            app.LTAWindowEditFieldLabel.Text = 'LTA-Window';
            app.LTAWindowEditField = uieditfield(app.STALTATab, 'numeric');
            app.LTAWindowEditField.ValueChangedFcn = createCallbackFcn(app, @LTAWindowEditFieldValueChanged, true);
            app.LTAWindowEditField.Value = 70;
            app.ToleranceEditFieldLabel = uilabel(app.STALTATab);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.STALTATab, 'numeric');
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 5;
            app.LocalizationTab = uitab(app.TabGroup);
            app.LocalizationTab.Title = 'Localization';
            app.startzEditFieldLabel = uilabel(app.LocalizationTab);
            app.startzEditFieldLabel.Text = 'start z';
            app.startzEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.startzEditField.Value = 5000;
            app.stepzEditFieldLabel = uilabel(app.LocalizationTab);
            app.stepzEditFieldLabel.Text = 'step z';
            app.stepzEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.stepzEditField.Value = 1000;
            app.endzEditFieldLabel = uilabel(app.LocalizationTab);
            app.endzEditFieldLabel.Text = 'end z';
            app.endzEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.endzEditField.Value = 5000;
            app.startvEditFieldLabel = uilabel(app.LocalizationTab);
            app.startvEditFieldLabel.Text = 'start v';
            app.startvEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.startvEditField.Value = 6100;
            app.stepvEditFieldLabel = uilabel(app.LocalizationTab);
            app.stepvEditFieldLabel.Text = 'step v';
            app.stepvEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.stepvEditField.Value = 1000;
            app.endvEditFieldLabel = uilabel(app.LocalizationTab);
            app.endvEditFieldLabel.Text = 'end v';
            app.endvEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.endvEditField.Value = 6100;
            app.ThicknesCrustEditFieldLabel = uilabel(app.LocalizationTab);
            app.ThicknesCrustEditFieldLabel.Text = 'Thicknes Crust';
            app.ThicknesCrustEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.ThicknesCrustEditField.Value = 10000;
            app.vpvsEditFieldLabel = uilabel(app.LocalizationTab);
            app.vpvsEditFieldLabel.Text = 'vp/vs';
            app.vpvsEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.vpvsEditField.Value = 1.74;
            app.NormDropDownLabel = uilabel(app.LocalizationTab);
            app.NormDropDownLabel.Text = 'Norm';
            app.NormDropDown = uidropdown(app.LocalizationTab);
            app.NormDropDown.Items = {'L2', 'L1'};
            app.NormDropDown.Value = 'L2';
            app.InversionMethodeDropDownLabel = uilabel(app.LocalizationTab);
            app.InversionMethodeDropDownLabel.Text = 'Inversion Methode';
            app.InversionMethodeDropDown = uidropdown(app.LocalizationTab);
            app.InversionMethodeDropDown.Items = {'grid', 'genetic', 'linear'};
            app.InversionMethodeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @InversionMethodeDropDownOpening, true);
            app.InversionMethodeDropDown.ValueChangedFcn = createCallbackFcn(app, @InversionMethodeDropDownValueChanged, true);
            app.InversionMethodeDropDown.Value = 'grid';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.PlotSTALTACheckBox = uicheckbox(app.OptionsTab);
            app.PlotSTALTACheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotSTALTACheckBoxValueChanged, true);
            app.PlotSTALTACheckBox.Text = 'Plot STA/LTA';
            app.PlotEventsCheckBox = uicheckbox(app.OptionsTab);
            app.PlotEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotEventsCheckBoxValueChanged, true);
            app.PlotEventsCheckBox.Text = 'Plot Events';
            app.PlotEventsCheckBox.Value = true;
            app.InversionCheckBox = uicheckbox(app.OptionsTab);
            app.InversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @InversionCheckBoxValueChanged, true);
            app.InversionCheckBox.Text = 'Inversion';
            app.InversionCheckBox.Value = true;
            app.ExcludeoldbadeventsCheckBox = uicheckbox(app.OptionsTab);
            app.ExcludeoldbadeventsCheckBox.Text = 'Exclude old bad events';
            app.ExcludeoldbadeventsCheckBox.Value = true;
            app.ShowalsoknowneventsCheckBox = uicheckbox(app.OptionsTab);
            app.ShowalsoknowneventsCheckBox.Text = 'Show also known events';
            app.ShowalsoknowneventsCheckBox.Value = true;
            app.ShowjustknowneventsCheckBox = uicheckbox(app.OptionsTab);
            app.ShowjustknowneventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowjustknowneventsCheckBoxValueChanged, true);
            app.ShowjustknowneventsCheckBox.Text = 'Show just known events';
            app.RelocateknowneventsCheckBox = uicheckbox(app.OptionsTab);
            app.RelocateknowneventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RelocateknowneventsCheckBoxValueChanged, true);
            app.RelocateknowneventsCheckBox.Text = 'Relocate known events';
            app.withpickingCheckBox = uicheckbox(app.OptionsTab);
            app.withpickingCheckBox.Text = 'with picking';
            app.plotdensityofEventsCheckBox = uicheckbox(app.OptionsTab);
            app.plotdensityofEventsCheckBox.Text = 'plot density of Events';
            app.plotdensityofEventsCheckBox.Value = true;
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results:';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eqfinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end